classdef RPR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ForwardSliderLabel        matlab.ui.control.Label
        ForwardSlider             matlab.ui.control.Slider
        AltitudeSliderLabel       matlab.ui.control.Label
        AltitudeSlider            matlab.ui.control.Slider
        AngleSliderLabel          matlab.ui.control.Label
        AngleSlider               matlab.ui.control.Slider
        HeightcmEditFieldLabel    matlab.ui.control.Label
        HeightcmEditField         matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel    matlab.ui.control.Label
        WeightkgEditField         matlab.ui.control.NumericEditField
        StartButton               matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        DuringTimeEditFieldLabel  matlab.ui.control.Label
        DuringTimeEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        position = [350,950,0]; % Point P of RPR3 
        parameter=[220;576.48;435;736;1236];%l0,l1,a3,rho_min,rho_max
        t=0:05:1;
        traj=ones(21,1)*[350,950,0];
        height=175;
        weight=65;
        lastP=[0,0,0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: AltitudeSlider
        function AltitudeSliderValueChanging(app, event)
            val = app.AltitudeSlider.Value; % Get RPR height
            % app.position(2) = val+app.position(2); % Set RPR height
            temp=[app.position(1)+app.ForwardSlider.Value,...
                  app.position(2)+app.AltitudeSlider.Value,...
                  app.position(3)+app.AngleSlider.Value/180*pi];
            rprplot(app.UIAxes,temp,app.parameter);
        end

        % Value changing function: AngleSlider
        function AngleSliderValueChanging(app, event)
            val = app.AngleSlider.Value; % Get RPR angle relative to ground
            % app.position(3) = val/180*pi+app.position(3); % Set RPR angle
            temp=[app.position(1)+app.ForwardSlider.Value,...
                  app.position(2)+app.AltitudeSlider.Value,...
                  app.position(3)+app.AngleSlider.Value/180*pi];
            rprplot(app.UIAxes,temp,app.parameter);
            
        end

        % Value changing function: ForwardSlider
        function ForwardSliderValueChanging(app, event)
            
            val = app.ForwardSlider.Value; % Get forward move
            % app.position(1) = val+app.position(1); % Set RPR forawrd move
            temp=[app.position(1)+app.ForwardSlider.Value,...
                  app.position(2)+app.AltitudeSlider.Value,...
                  app.position(3)+app.AngleSlider.Value/180*pi];
            rprplot(app.UIAxes,temp,app.parameter);
        end

        % Value changed function: AngleSlider
        function AngleSliderValueChanged(app, event)
            val = app.AngleSlider.Value; % Get RPR angle relative to ground
            % app.position(3) = val/180*pi+app.position(3); % Set RPR angle
            temp=[app.position(1)+app.ForwardSlider.Value,...
                  app.position(2)+app.AltitudeSlider.Value,...
                  app.position(3)+app.AngleSlider.Value/180*pi];
            myT=0:0.02:0.2;
            app.traj=[transpose(linspace(app.position(1)+app.lastP(1),temp(1),length(myT))),...
                      transpose(linspace(app.position(2)+app.lastP(2),temp(2),length(myT))),...
                      transpose(linspace(app.position(3)+app.lastP(3),temp(3),length(myT)))];
            app.lastP(3)=app.AngleSlider.Value/180*pi;
            trajplot(app.UIAxes,myT,app.traj,app.parameter)
            % rprplot(app.UIAxes,temp,app.parameter);
        end

        % Value changed function: AltitudeSlider
        function AltitudeSliderValueChanged(app, event)
            val = app.AltitudeSlider.Value; % Get RPR height
            % app.position(2) = val+app.position(2); % Set RPR height
            temp=[app.position(1)+app.ForwardSlider.Value,...
                  app.position(2)+app.AltitudeSlider.Value,...
                  app.position(3)+app.AngleSlider.Value/180*pi];
            myT=0:0.02:0.2;
            app.traj=[transpose(linspace(app.position(1)+app.lastP(1),temp(1),length(myT))),...
                      transpose(linspace(app.position(2)+app.lastP(2),temp(2),length(myT))),...
                      transpose(linspace(app.position(3)+app.lastP(3),temp(3),length(myT)))];
            app.lastP(2)=app.AltitudeSlider.Value;
            trajplot(app.UIAxes,myT,app.traj,app.parameter)
            % rprplot(app.UIAxes,temp,app.parameter);
        end

        % Value changed function: ForwardSlider
        function ForwardSliderValueChanged(app, event)
            val = app.ForwardSlider.Value; % Get forward move
            % app.position(1) = val+app.position(1); % Set RPR forawrd move
            temp=[app.position(1)+app.ForwardSlider.Value,...
                  app.position(2)+app.AltitudeSlider.Value,...
                  app.position(3)+app.AngleSlider.Value/180*pi];
            myT=0:0.02:0.2;
            app.traj=[transpose(linspace(app.position(1)+app.lastP(1),temp(1),length(myT))),...
                      transpose(linspace(app.position(2)+app.lastP(2),temp(2),length(myT))),...
                      transpose(linspace(app.position(3)+app.lastP(3),temp(3),length(myT)))];
            app.lastP(1)=app.ForwardSlider.Value;
            trajplot(app.UIAxes,myT,app.traj,app.parameter)
            % rprplot(app.UIAxes,temp,app.parameter);
        end

        % Value changed function: HeightcmEditField
        function HeightcmEditFieldValueChanged(app, event)
            app.height = app.HeightcmEditField.Value;
            app.traj=trajectory(app.height,app.weight,app.parameter,app.t);
        end

        % Value changed function: WeightkgEditField
        function WeightkgEditFieldValueChanged(app, event)
            app.weight = app.WeightkgEditField.Value;
            app.traj=trajectory(app.height,app.weight,app.parameter,app.t);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            trajplot(app.UIAxes,app.t,app.traj,app.parameter)
            app.position=app.traj(end,:);
        end

        % Value changed function: DuringTimeEditField
        function DuringTimeEditFieldValueChanged(app, event)
            app.t=0:0.05:app.DuringTimeEditField.Value;
            app.traj=trajectory(app.height,app.weight,app.parameter,app.t);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3-RPRrobot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 800];
            app.UIAxes.YLim = [0 1400];
            app.ForwardSliderLabel = uilabel(app.UIFigure);
            app.ForwardSliderLabel.Text = 'Forward';
            app.ForwardSlider = uislider(app.UIFigure);
            app.ForwardSlider.Limits = [-200 200];
            app.ForwardSlider.ValueChangedFcn = createCallbackFcn(app, @ForwardSliderValueChanged, true);
            app.ForwardSlider.ValueChangingFcn = createCallbackFcn(app, @ForwardSliderValueChanging, true);
            app.AltitudeSliderLabel = uilabel(app.UIFigure);
            app.AltitudeSliderLabel.Text = 'Altitude';
            app.AltitudeSlider = uislider(app.UIFigure);
            app.AltitudeSlider.Limits = [-200 200];
            app.AltitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AltitudeSliderValueChanged, true);
            app.AltitudeSlider.ValueChangingFcn = createCallbackFcn(app, @AltitudeSliderValueChanging, true);
            app.AngleSliderLabel = uilabel(app.UIFigure);
            app.AngleSliderLabel.Text = 'Angle';
            app.AngleSlider = uislider(app.UIFigure);
            app.AngleSlider.Limits = [-20 20];
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @AngleSliderValueChanged, true);
            app.AngleSlider.ValueChangingFcn = createCallbackFcn(app, @AngleSliderValueChanging, true);
            app.HeightcmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightcmEditFieldLabel.Text = 'Height(cm)';
            app.HeightcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightcmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightcmEditFieldValueChanged, true);
            app.HeightcmEditField.Value = 170;
            app.WeightkgEditFieldLabel = uilabel(app.UIFigure);
            app.WeightkgEditFieldLabel.Text = 'Weight(kg)';
            app.WeightkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeightkgEditField.ValueChangedFcn = createCallbackFcn(app, @WeightkgEditFieldValueChanged, true);
            app.WeightkgEditField.Value = 65;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DuringTimeEditFieldLabel = uilabel(app.UIFigure);
            app.DuringTimeEditFieldLabel.Text = 'DuringTime';
            app.DuringTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DuringTimeEditField.ValueChangedFcn = createCallbackFcn(app, @DuringTimeEditFieldValueChanged, true);
            app.DuringTimeEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end